classdef Digital_Audio_Workstation_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlayAudioPanel                 matlab.ui.container.Panel
        FullResetButton                matlab.ui.control.Button
        StopSoundButton                matlab.ui.control.Button
        ApplySelectedEffectsPlaySoundButton  matlab.ui.control.Button
        DAWDigitalAudioWorkstationLabel  matlab.ui.control.Label
        AudioEffectsPanel              matlab.ui.container.Panel
        FrequencySlider                matlab.ui.control.Slider
        FrequencySliderLabel           matlab.ui.control.Label
        EchoTempoBPMEditField          matlab.ui.control.NumericEditField
        EchoTempoBPMEditFieldLabel     matlab.ui.control.Label
        ApplyEchoButton                matlab.ui.control.StateButton
        NoteDivisionDropDown           matlab.ui.control.DropDown
        NoteDivisionDropDownLabel      matlab.ui.control.Label
        DefaultTempo120BPMLabel        matlab.ui.control.Label
        ApplyReverbButton              matlab.ui.control.StateButton
        ReverbEffectLabel              matlab.ui.control.Label
        EchoEffectLabel                matlab.ui.control.Label
        DirectionDropDown              matlab.ui.control.DropDown
        DirectionLabel                 matlab.ui.control.Label
        PanTypeDropDown                matlab.ui.control.DropDown
        PanTypeLabel                   matlab.ui.control.Label
        AmplitudeSlider                matlab.ui.control.Slider
        AmplitudeSliderLabel           matlab.ui.control.Label
        RythmicAutoPanningEffectLabel  matlab.ui.control.Label
        WaveTypeDropDown               matlab.ui.control.DropDown
        WaveTypeLabel                  matlab.ui.control.Label
        FadeInOutEffectLabel           matlab.ui.control.Label
        SpeedofEffectFrequencySlider   matlab.ui.control.Slider
        SpeedofEffectFrequencySliderLabel  matlab.ui.control.Label
        TremoloEffectLabel             matlab.ui.control.Label
        DefaultTimingStartStop0Label   matlab.ui.control.Label
        StopTimeEditField              matlab.ui.control.NumericEditField
        StopTimeEditFieldLabel         matlab.ui.control.Label
        FadeTypeDropDown               matlab.ui.control.DropDown
        FadeTypeDropDownLabel          matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel        matlab.ui.control.Label
        DepthofAmplitudeSlider         matlab.ui.control.Slider
        DepthofAmplitudeSliderLabel    matlab.ui.control.Label
        FadeOutButton                  matlab.ui.control.StateButton
        FadeInButton                   matlab.ui.control.StateButton
        FileSelectionPanel             matlab.ui.container.Panel
        ExampleNameOutputwavorOutputmp4Label  matlab.ui.control.Label
        InputFileSaveNameEditField     matlab.ui.control.EditField
        InputFileSaveNameEditFieldLabel  matlab.ui.control.Label
        SaveAlteredAudioLabel          matlab.ui.control.Label
        SaveAudioFileButton            matlab.ui.control.Button
        LengthofFileInTimesEditField   matlab.ui.control.NumericEditField
        LengthofFileInTimesEditFieldLabel  matlab.ui.control.Label
        UploadAudioInputLabel          matlab.ui.control.Label
        SelectedAudioFileEditField     matlab.ui.control.EditField
        SelectedAudioFileLabel         matlab.ui.control.Label
        UploadAudioFileButton          matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %global variables
        file
        output
        Fs
        sw
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadAudioFileButton
        function UploadAudioFileButtonPushed(app, event)
            
            %Load filer explorer, user selects file
            app.file = uigetfile({'*.wav';'*.mp3';'*.m4a';'*.*'});
            if app.file ~= 0
                %display selected file on GUI
                app.SelectedAudioFileEditField.Value = app.file; 
                %initialize variables and save audio signal and sampling
                %frequency from the selected file
                [app.sw, app.Fs] = audioread(app.file);
                %Incase of repeated columns/2D audio signal, save as 1
                %column/1D
                app.sw = app.sw(:,1);
                %Initialize global output as input
                app.output = app.sw;
                %display time of audio file on GUI
                app.LengthofFileInTimesEditField.Value = length(app.sw)/app.Fs;
                %plot audio signal with respect to time
                t = [0:length(app.sw)-1].*(1./app.Fs); 
                t = t(:); %changes time to only have 1 column
                plot(app.UIAxes,t,app.sw);
                %Set all default values required for GUI
                app.EchoTempoBPMEditField.Value = 120;
            end
            
        end

        % Button pushed function: SaveAudioFileButton
        function SaveAudioFileButtonPushed(app, event)
            %saves the output file with the effects to your desired name
            %writes it as amplitude vs samples
            fileOut = app.InputFileSaveNameEditField.Value; 
            %If there is an audio file input, allow saving
            if app.SelectedAudioFileEditField.Value ~= ""
                
                %if save name is in .wav, save as .wav
                if (contains(lower(fileOut),'.wav') == 1)
                    audiowrite(fileOut,app.output,app.Fs);
                    app.InputFileSaveNameEditField.Value = 'Saved!';
                    pause(1);
                    app.InputFileSaveNameEditField.Value = "";
                    
                %If save name is in .mp4, save as .mp4   
                elseif (contains(lower(fileOut),'.mp4') == 1)
                    audiowrite(fileOut,app.output,app.Fs);
                    app.InputFileSaveNameEditField.Value = 'Saved!';
                    pause(1);
                    app.InputFileSaveNameEditField.Value = "";
                    
                %If save name is invalid (no file type), do not save, no error in code    
                else
                    app.InputFileSaveNameEditField.Value = 'Invalid Save Name!';
                    pause(1);
                    app.InputFileSaveNameEditField.Value = "";
                end
            %If there is no audio file input, do not save, no error in code
            else 
                app.InputFileSaveNameEditField.Value = 'No Output Audio Found';
                pause(1);
                app.InputFileSaveNameEditField.Value = "";
            end
                
        end

        % Button pushed function: ApplySelectedEffectsPlaySoundButton
        function ApplySelectedEffectsPlaySoundButtonPushed(app, event)
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  Fade In/Out Effect  %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %Defines the start and stop time of the fade effect
            %initialized by user
            FadeStart = app.StartTimeEditField.Value;
            FadeStop = app.StopTimeEditField.Value;     
            
            %default case for when you want to apply fade, but no time is
            %defined
            if ((FadeStart == 0) && (FadeStop == 0)) || (FadeStart < 0) || (FadeStop > app.LengthofFileInTimesEditField.Value)
                
                % defines the number of samples from the input
                numOfSamples = length(app.sw);
                %defines the start and stop time for the default case
                %fades the entire signal
                sampleStart = 0;
                sampleStop = length(app.sw);
                
            else
                %defines the start and stop samples given valid user input
                sampleStart = FadeStart*app.Fs;
                sampleStop = FadeStop*app.Fs;
                %finds the number of samples to be faded
                t = sampleStart:sampleStop;
                numOfSamples = length(t);
                
            end
            
            %makes sure that when the start time is zero, the sample we
            %start fading at is 1 because matlab arrays aren't defined
            %starting from 0
            if(sampleStart == 0)
                
                sampleStart = sampleStart+1;
                t = sampleStart:sampleStop;
                numOfSamples = length(t);
                
            end
            %For the case of Linear fading
            if string(app.FadeTypeDropDown.Value) == 'Linear' 
                %defining an array of the same size as the input signal
                %with value increasing from 0 to 1 linearly
                temp = linspace(0,1,numOfSamples); 
                %changes array to have 1 column
                temp = temp(:); 
                %initializes the fadein and fadeout effect arrays
                fadeIn = temp; 
                fadeOut = 1-temp;
                 
                %only applies the fadein effect
                if (app.FadeInButton.Value == 1) && (app.FadeOutButton.Value == 0)
                    %copies the input signal till the start time
                    in(1:sampleStart) = app.sw(1:sampleStart);
                    %copies the input signal and applies the fade effect
                    %for the time defined
                    in(sampleStart:sampleStop) = fadeIn.* app.sw(sampleStart:sampleStop);
                    %copies the input signal till the end of the input
                    %signal
                    in(sampleStop:length(app.sw)) = app.sw(sampleStop:end);
                    app.output = in;
              
                %applies the fadeout effect    
                elseif (app.FadeInButton.Value == 0) && (app.FadeOutButton.Value == 1)
                    %copies the input signal till the start time
                    out(1:sampleStart) = app.sw(1:sampleStart);
                    %copies the input signal and applies the fade effect
                    %for the time defined
                    out(sampleStart:sampleStop) = fadeOut.* app.sw(sampleStart:sampleStop);
                    %copies the input signal till the end of the input
                    %signal
                    out(sampleStop:length(app.sw)) = app.sw(sampleStop:end);
                    app.output = out;
                
                %applies both the fadein and fadeout effects
                elseif (app.FadeInButton.Value == 1) && (app.FadeOutButton.Value == 1)
                    
                    %applies the fadein effect for the period defined
                    in(1:sampleStart) = app.sw(1:sampleStart);
                    in(sampleStart:sampleStop) = fadeIn.* app.sw(sampleStart:sampleStop);
                    in(sampleStop:length(app.sw)) = app.sw(sampleStop:end);
                    
                    in = in(:);
                    
                    %applies the fadeout effect onto the fadedin signal
                    both(1:sampleStart) = in(1:sampleStart);
                    both(sampleStart:sampleStop) = fadeOut.* in(sampleStart:sampleStop);
                    both(sampleStop:length(app.sw)) = in(sampleStop:end);
                    app.output = both;
                else
                    
                    app.output = app.sw;
                end

            %For the case of exponential fading   
            elseif string(app.FadeTypeDropDown.Value) == 'Exponential'
                %defining an array of the same size as the input signal
                %with value increasing from 1 to 0 linearly
                temp = linspace(1, 0, numOfSamples);
                temp = temp(:);
                fadeIn = 1-temp.^2;
                %defining an array of the same size as the input signal
                %with value increasing from 1 to 0 linearly
                temp = linspace(0, 1, numOfSamples);
                temp = temp(:);
                fadeOut = 1-temp.^2;
                
                %only applies the fadein effect
                if (app.FadeInButton.Value == 1) && (app.FadeOutButton.Value == 0)
                    %copies the input signal till the start time
                    in(1:sampleStart) = app.sw(1:sampleStart);
                    %copies the input signal and applies the fade effect
                    %for the time defined
                    in(sampleStart:sampleStop) = fadeIn.* app.sw(sampleStart:sampleStop);
                    %copies the input signal till the end of the input
                    %signal
                    in(sampleStop:length(app.sw)) = app.sw(sampleStop:end);
                    app.output = in;
              
                %applies the fadeout effect    
                elseif (app.FadeInButton.Value == 0) && (app.FadeOutButton.Value == 1)
                    %copies the input signal till the start time
                    out(1:sampleStart) = app.sw(1:sampleStart);
                    %copies the input signal and applies the fade effect
                    %for the time defined
                    out(sampleStart:sampleStop) = fadeOut.* app.sw(sampleStart:sampleStop);
                    %copies the input signal till the end of the input
                    %signal
                    out(sampleStop:length(app.sw)) = app.sw(sampleStop:end);
                    app.output = out;
                
                %applies both the fadein and fadeout effects
                elseif (app.FadeInButton.Value == 1) && (app.FadeOutButton.Value == 1)
                    
                    %applies the fadein effect for the period defined
                    in(1:sampleStart) = app.sw(1:sampleStart);
                    in(sampleStart:sampleStop) = fadeIn.* app.sw(sampleStart:sampleStop);
                    in(sampleStop:length(app.sw)) = app.sw(sampleStop:end);
                    
                    in = in(:);
                    
                    %applies the fadeout effect onto the fadedin signal
                    both(1:sampleStart) = in(1:sampleStart);
                    both(sampleStart:sampleStop) = fadeOut.* in(sampleStart:sampleStop);
                    both(sampleStop:length(app.sw)) = in(sampleStop:end);
                    app.output = both;
                else
                    
                    app.output = app.sw;
                end
            end 
            
                       
            %%%%%%%%%%%%%%%%%%%%%%
            %%  Tremolo Effect  %%
            %%%%%%%%%%%%%%%%%%%%%%
            
            %values initialized by the user to use the treomlo effect
            depth = app.DepthofAmplitudeSlider.Value; % [0,100] 
            speed = app.SpeedofEffectFrequencySlider.Value; % [0.1,20] Hz

            %only runs code if depth does not equal to zero
            if (depth ~= 0)
                %defines time with the same length as the input signal
                t = [0:length(app.output)-1].*(1./app.Fs); 
                t = t(:); 
                amp = 0.5*(depth/100);   % amplitude of the tremolo effect
                offset = 0.5; % offset of the tremolo effect
                
                % Synthesize modulation signal  
                f = speed;  % speed of effect 
                
                %defines the oscillation type of the tremolo effect
                if string(app.WaveTypeDropDown.Value) == 'Sine Wave'
                    wave = sin(2*pi*f.*t);
                elseif string(app.WaveTypeDropDown.Value) == 'Square Wave'
                    wave = square(2*pi*f.*t);
                elseif string(app.WaveTypeDropDown.Value) == 'Triangle Wave'
                    wave = sawtooth(2*pi*f.*t);
                end
                
                %the modulated/tremolo signal with the amplitude and offset
                %applied
                mod = amp .* wave + offset; 
             
                % Modulate the amplitude of the carrier by the modulator 
                app.output = app.output(:);
                %applies the effect onto the signal
                app.output = app.output .* mod;
            end
            
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  Echo Effect - Feedback  %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
           
            if app.ApplyEchoButton.Value == 1
                
                % Known tempo of recording
                beatsPerMin = app.EchoTempoBPMEditField.Value; % units of beats per minute
                                
                % Calculate beats per second
                beatsPerSec = beatsPerMin / 60; % 1 minute / 60 seconds
                
                % Calculate # of seconds per beat
                secPerBeat = 1/beatsPerSec;
                
                % note div - duration of delayed echo, the larger the
                % noteDiv value the larger the delay before echo
                % 4 = whole, 2 = half, 1 = quarter, 0.5 = 8th, 0.25 = 16th
                switch (app.NoteDivisionDropDown.Value)
                    case "Whole"
                        noteDiv = 4;
                    case "Half"
                        noteDiv = 2;
                    case "Quarter"
                        noteDiv = 1;
                    case "Eighth"
                        noteDiv = 0.5;
                    case "Sixteenth"
                        noteDiv = 0.1;   
                end
                                
                % Calculate delay time in seconds
                timeSec = noteDiv * secPerBeat;
                
                % Calculates delay in samples
                d = fix(timeSec * app.Fs); % round to nearest integer sample
                a = -0.75; % amplitude of delay branch
                
                % Index each element of our signal to create the output
                N = length(app.output);
                %define new array of the same size as output
                y = zeros(N,1);
                app.output = app.output(:);
                for n = 1:N
                    % When the sample number is less than the time delay
                    % Avoid indexing a negative sample number
                    %save values of the input array into the new array for
                    %the delay period defined
                    if n < d+1
                        % output = input
                        y(n,1) = app.output(n,1);
                        % Now add in the delayed signal
                    %plays the echo effect onto the input signal by using
                    %the previously saved values
                    else
                        % output = input + delayed version of output
                        % reduce relative amplitude of delay to 3/4
                        y(n,1) = app.output(n,1) + (-a)*y(n-d,1);
                    end
                end
                %Save echo as output
                app.output = y;
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%  Rythmic Auto Panning Effect  %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %only applies for when the amplitude value does not equal zero
            if (app.AmplitudeSlider.Value ~= 0)
                %time defined for the length of the input signal
                t = [0:length(app.sw)-1].*(1./app.Fs); 
                t = t(:); 
                f = app.FrequencySlider.Value; % Frequency of panning LFO
                % Select Amplitude [-100,100] based on direction
                if app.DirectionDropDown.Value == 'Left to Right'
                    A = -1 * app.AmplitudeSlider.Value; 
                elseif app.DirectionDropDown.Value == 'Right to Left'
                    A = app.AmplitudeSlider.Value;
                end
               
                % Generate sine wave LFO based on f and t
                panValue = A*sin(2*pi*f*t);
                %User selected pan type for linear,squareroot, or sine-law
                %condition application
                panType = app.PanTypeDropDown.Value; 
                switch panType
                    case 'Linear'
                        panType = 1;
                    case 'SquareRoot'
                        panType = 2;
                    case 'Sine-law'
                        panType = 3;
                end
                
                % Run pan function
                app.output = app.output(:);
                %run the function to get the output signal
                app.output = pan(app.output,panValue,panType);
            end
            
            % Pan Function
            function [out] = pan(in, panValue, panType)
               
                % Convert pan value to a normalized scale
                panTransform = (panValue/200) + 0.5;
                
                % Conditional statements determining panType
                if panType == 1                   
                    leftAmp = 1 - panTransform;                 %Linear
                    rightAmp = panTransform;
                elseif panType == 2
                    leftAmp = sqrt(1 - panTransform);           %SquareRoot
                    rightAmp = sqrt(panTransform);
                elseif panType == 3
                    leftAmp = sin((1 - panTransform) * (pi/2)); %Sine-law
                    rightAmp = sin(panTransform * (pi/2));
                end
                
                %Multiply audio file by left/right amplitudes
                leftChannel = leftAmp.*in;
                rightChannel = rightAmp.*in;
                out = [leftChannel,rightChannel];
            end


            %%%%%%%%%%%%%%%%%%%%%
            %%  Reverb Effect  %%
            %%%%%%%%%%%%%%%%%%%%%
            
            %Apply reverb if reverb button is active
            if (app.ApplyReverbButton.Value == 1)
                
                %if panning effect is applied, convolve impulse with regular and
                %panned audio output
                if app.AmplitudeSlider.Value > 0
                 
                    [h] = audioread('reverbIR.wav'); % Stereo I
                    % Perform convolution of signals
                    yLeft = conv(app.output(:,1),h(:,1));
                    yRight = conv(app.output(:,2),h(:,2));
                    app.output = [yLeft,yRight];
                
                    %else convolve impulse with regular output  
                else
                    
                    app.output = app.output(:);
                    [h] = audioread('reverbIR.wav'); % Stereo I
                    % Perform convolution of signals
                    yLeft = conv(app.output,h(:,1));
                    yRight = conv(app.output,h(:,2));
                    app.output = [yLeft,yRight];
                
                end    
            
            end
               
            % Play Editted Output Sound and Plot its Waveform
            t = [0:length(app.output)-1].*(1/app.Fs); 
            t = t(:); 
            sound(app.output, app.Fs);
            plot(app.UIAxes,t,app.output);   
            
                        
        end

        % Button pushed function: StopSoundButton
        function StopSoundButtonPushed(app, event)
            
            %Stop sound/clear audio
            clear sound;
            
        end

        % Button pushed function: FullResetButton
        function FullResetButtonPushed(app, event)
            
            %Reset all values to initialized conditions
            clear sound;
            
            set(app.FadeOutButton,'value',0);
            set(app.FadeInButton,'value',0);
            set(app.ApplyReverbButton,'value',0);
            set(app.ApplyEchoButton,'value',0);

            set(app.EchoTempoBPMEditField,'value',120);
            set(app.StartTimeEditField,'value',0);
            set(app.StopTimeEditField,'value',0);
            set(app.InputFileSaveNameEditField,'value','');

            set(app.FadeTypeDropDown,'value','Linear');
            set(app.WaveTypeDropDown,'value','Sine Wave');
            set(app.PanTypeDropDown,'value','Linear');
            set(app.DirectionDropDown,'value','Left to Right');
            set(app.NoteDivisionDropDown,'value','Whole');

            set(app.AmplitudeSlider,'value',0);
            set(app.DepthofAmplitudeSlider,'value',0);
            set(app.SpeedofEffectFrequencySlider,'value',0.1);
            set(app.FrequencySlider,'value',0.1);
            
            t = [0:length(app.sw)-1].*(1./app.Fs); 
            t = t(:); 
            plot(app.UIAxes,t,app.sw);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sound Plot')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Ampliude')
            zlabel(app.UIAxes, 'Z')
            app.FileSelectionPanel = uipanel(app.UIFigure);
            app.FileSelectionPanel.Title = 'File Selection';
            app.UploadAudioFileButton = uibutton(app.FileSelectionPanel, 'push');
            app.UploadAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadAudioFileButtonPushed, true);
            app.UploadAudioFileButton.Text = 'Upload Audio File';
            app.SelectedAudioFileLabel = uilabel(app.FileSelectionPanel);
            app.SelectedAudioFileLabel.Text = {'Selected'; 'Audio File:'};
            app.SelectedAudioFileEditField = uieditfield(app.FileSelectionPanel, 'text');
            app.SelectedAudioFileEditField.Editable = 'off';
            app.UploadAudioInputLabel = uilabel(app.FileSelectionPanel);
            app.UploadAudioInputLabel.Text = 'Upload Audio Input';
            app.LengthofFileInTimesEditFieldLabel = uilabel(app.FileSelectionPanel);
            app.LengthofFileInTimesEditFieldLabel.Text = {'Length of File'; 'In Time (s)'};
            app.LengthofFileInTimesEditField = uieditfield(app.FileSelectionPanel, 'numeric');
            app.LengthofFileInTimesEditField.Editable = 'off';
            app.SaveAudioFileButton = uibutton(app.FileSelectionPanel, 'push');
            app.SaveAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAudioFileButtonPushed, true);
            app.SaveAudioFileButton.Text = 'Save Audio File';
            app.SaveAlteredAudioLabel = uilabel(app.FileSelectionPanel);
            app.SaveAlteredAudioLabel.Text = 'Save Altered Audio';
            app.InputFileSaveNameEditFieldLabel = uilabel(app.FileSelectionPanel);
            app.InputFileSaveNameEditFieldLabel.Text = {'Input File'; 'Save Name'};
            app.InputFileSaveNameEditField = uieditfield(app.FileSelectionPanel, 'text');
            app.ExampleNameOutputwavorOutputmp4Label = uilabel(app.FileSelectionPanel);
            app.ExampleNameOutputwavorOutputmp4Label.Text = 'Example Name: Output.wav  or Output.mp4';
            app.AudioEffectsPanel = uipanel(app.UIFigure);
            app.AudioEffectsPanel.Title = 'Audio Effects';
            app.FadeInButton = uibutton(app.AudioEffectsPanel, 'state');
            app.FadeInButton.Text = 'Fade-In';
            app.FadeOutButton = uibutton(app.AudioEffectsPanel, 'state');
            app.FadeOutButton.Text = 'Fade-Out';
            app.DepthofAmplitudeSliderLabel = uilabel(app.AudioEffectsPanel);
            app.DepthofAmplitudeSliderLabel.Text = {'Depth of'; 'Amplitude'};
            app.DepthofAmplitudeSlider = uislider(app.AudioEffectsPanel);
            app.StartTimeEditFieldLabel = uilabel(app.AudioEffectsPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.AudioEffectsPanel, 'numeric');
            app.FadeTypeDropDownLabel = uilabel(app.AudioEffectsPanel);
            app.FadeTypeDropDownLabel.Text = 'Fade Type';
            app.FadeTypeDropDown = uidropdown(app.AudioEffectsPanel);
            app.FadeTypeDropDown.Items = {'Linear', 'Exponential'};
            app.FadeTypeDropDown.Value = 'Linear';
            app.StopTimeEditFieldLabel = uilabel(app.AudioEffectsPanel);
            app.StopTimeEditFieldLabel.Text = 'Stop Time';
            app.StopTimeEditField = uieditfield(app.AudioEffectsPanel, 'numeric');
            app.DefaultTimingStartStop0Label = uilabel(app.AudioEffectsPanel);
            app.DefaultTimingStartStop0Label.Text = 'Default Timing: Start/Stop = 0';
            app.TremoloEffectLabel = uilabel(app.AudioEffectsPanel);
            app.TremoloEffectLabel.Text = 'Tremolo Effect';
            app.SpeedofEffectFrequencySliderLabel = uilabel(app.AudioEffectsPanel);
            app.SpeedofEffectFrequencySliderLabel.Text = {'Speed '; 'of Effect'; '(Frequency)'};
            app.SpeedofEffectFrequencySlider = uislider(app.AudioEffectsPanel);
            app.SpeedofEffectFrequencySlider.Limits = [0 20];
            app.SpeedofEffectFrequencySlider.MajorTicks = [0.1 4 8 12 16 20];
            app.SpeedofEffectFrequencySlider.MinorTicks = [0.1 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20];
            app.FadeInOutEffectLabel = uilabel(app.AudioEffectsPanel);
            app.FadeInOutEffectLabel.Text = 'Fade In/Out Effect';
            app.WaveTypeLabel = uilabel(app.AudioEffectsPanel);
            app.WaveTypeLabel.Text = 'Wave Type:';
            app.WaveTypeDropDown = uidropdown(app.AudioEffectsPanel);
            app.WaveTypeDropDown.Items = {'Sine Wave', 'Square Wave', 'Triangle Wave'};
            app.WaveTypeDropDown.Value = 'Sine Wave';
            app.RythmicAutoPanningEffectLabel = uilabel(app.AudioEffectsPanel);
            app.RythmicAutoPanningEffectLabel.Text = 'Rythmic Auto Panning Effect';
            app.AmplitudeSliderLabel = uilabel(app.AudioEffectsPanel);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.AudioEffectsPanel);
            app.PanTypeLabel = uilabel(app.AudioEffectsPanel);
            app.PanTypeLabel.Text = 'Pan Type:';
            app.PanTypeDropDown = uidropdown(app.AudioEffectsPanel);
            app.PanTypeDropDown.Items = {'Linear', 'SquareRoot', 'Sine-law'};
            app.PanTypeDropDown.Value = 'Linear';
            app.DirectionLabel = uilabel(app.AudioEffectsPanel);
            app.DirectionLabel.Text = 'Direction:';
            app.DirectionDropDown = uidropdown(app.AudioEffectsPanel);
            app.DirectionDropDown.Items = {'Left to Right', 'Right to Left'};
            app.DirectionDropDown.Value = 'Left to Right';
            app.EchoEffectLabel = uilabel(app.AudioEffectsPanel);
            app.EchoEffectLabel.Text = 'Echo Effect';
            app.ReverbEffectLabel = uilabel(app.AudioEffectsPanel);
            app.ReverbEffectLabel.Text = 'Reverb Effect';
            app.ApplyReverbButton = uibutton(app.AudioEffectsPanel, 'state');
            app.ApplyReverbButton.Text = 'Apply Reverb';
            app.DefaultTempo120BPMLabel = uilabel(app.AudioEffectsPanel);
            app.DefaultTempo120BPMLabel.Text = 'Default Tempo: 120 BPM';
            app.NoteDivisionDropDownLabel = uilabel(app.AudioEffectsPanel);
            app.NoteDivisionDropDownLabel.Text = 'Note Division:';
            app.NoteDivisionDropDown = uidropdown(app.AudioEffectsPanel);
            app.NoteDivisionDropDown.Items = {'Whole', 'Half', 'Quarter', 'Eighth', 'Sixteenth'};
            app.NoteDivisionDropDown.Value = 'Whole';
            app.ApplyEchoButton = uibutton(app.AudioEffectsPanel, 'state');
            app.ApplyEchoButton.Text = 'Apply Echo';
            app.EchoTempoBPMEditFieldLabel = uilabel(app.AudioEffectsPanel);
            app.EchoTempoBPMEditFieldLabel.Text = {'Echo Tempo'; '(BPM)'};
            app.EchoTempoBPMEditField = uieditfield(app.AudioEffectsPanel, 'numeric');
            app.FrequencySliderLabel = uilabel(app.AudioEffectsPanel);
            app.FrequencySliderLabel.Text = 'Frequency';
            app.FrequencySlider = uislider(app.AudioEffectsPanel);
            app.FrequencySlider.Limits = [0 20];
            app.FrequencySlider.MajorTicks = [0.1 4 8 12 16 20];
            app.FrequencySlider.MinorTicks = [0.1 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5 5.5 6 6.5 7 7.5 8 8.5 9 9.5 10 10.5 11 11.5 12 12.5 13 13.5 14 14.5 15 15.5 16 16.5 17 17.5 18 18.5 19 19.5 20];
            app.DAWDigitalAudioWorkstationLabel = uilabel(app.UIFigure);
            app.DAWDigitalAudioWorkstationLabel.Text = 'DAW (Digital Audio Workstation)';
            app.PlayAudioPanel = uipanel(app.UIFigure);
            app.PlayAudioPanel.Title = 'Play Audio';
            app.ApplySelectedEffectsPlaySoundButton = uibutton(app.PlayAudioPanel, 'push');
            app.ApplySelectedEffectsPlaySoundButton.ButtonPushedFcn = createCallbackFcn(app, @ApplySelectedEffectsPlaySoundButtonPushed, true);
            app.ApplySelectedEffectsPlaySoundButton.Text = {'Apply Selected Effects'; '&'; 'Play Sound'};
            app.StopSoundButton = uibutton(app.PlayAudioPanel, 'push');
            app.StopSoundButton.ButtonPushedFcn = createCallbackFcn(app, @StopSoundButtonPushed, true);
            app.StopSoundButton.Text = 'Stop Sound';
            app.FullResetButton = uibutton(app.PlayAudioPanel, 'push');
            app.FullResetButton.ButtonPushedFcn = createCallbackFcn(app, @FullResetButtonPushed, true);
            app.FullResetButton.Text = 'Full Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Digital_Audio_Workstation_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end